classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        RunGradientButton              matlab.ui.control.Button
        CongestionmethodDropDown       matlab.ui.control.DropDown
        CongestionmethodDropDownLabel  matlab.ui.control.Label
        LimitSpinner                   matlab.ui.control.Spinner
        LimitSpinnerLabel              matlab.ui.control.Label
        CongestionLimitSwitch          matlab.ui.control.Switch
        CongestionLimitSwitchLabel     matlab.ui.control.Label
        ConstantdataovertimeCheckBox   matlab.ui.control.CheckBox
        GotoOuputsButton               matlab.ui.control.Button
        ManualRefreshButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        BuyPriceDropDown               matlab.ui.control.DropDown
        BuyPriceDropDownLabel          matlab.ui.control.Label
        SellpriceDropDown              matlab.ui.control.DropDown
        SellpriceDropDownLabel         matlab.ui.control.Label
        GenerationDataDropDown         matlab.ui.control.DropDown
        GenerationDataDropDownLabel    matlab.ui.control.Label
        ConsumptionDataDropDown        matlab.ui.control.DropDown
        ConsumptionDataDropDownLabel   matlab.ui.control.Label
        ActorsSpinner                  matlab.ui.control.Spinner
        ActorsSpinnerLabel             matlab.ui.control.Label
        PricingMechanismDropDown       matlab.ui.control.DropDown
        PricingMechanismDropDownLabel  matlab.ui.control.Label
        HorizonEditField               matlab.ui.control.NumericEditField
        HorizonLabel                   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        TimestepEditField              matlab.ui.control.NumericEditField
        TimestepLabel                  matlab.ui.control.Label
        CplatformEditField             matlab.ui.control.NumericEditField
        C_plaformLabel                 matlab.ui.control.Label
        PgridEditField                 matlab.ui.control.NumericEditField
        P_GridLabel                    matlab.ui.control.Label
        CfeedbackEditField             matlab.ui.control.NumericEditField
        C_FeedbackFeeLabel             matlab.ui.control.Label
        CnetworkfeeEditField           matlab.ui.control.NumericEditField
        C_NetwrokFeeLabel              matlab.ui.control.Label
        OutputsPanel                   matlab.ui.container.Panel
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        GotoInputsButton               matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        NetworkAxes                    matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
    
 function updateInputTable(app)

    n = app.ActorsSpinner.Value;

    % If more than 7 actors are selected, force all input modes to Custom
if n > 7
    app.ConsumptionDataDropDown.Value = 'Custom';
    app.GenerationDataDropDown.Value  = 'Custom';
    app.BuyPriceDropDown.Value        = 'Custom';
    app.SellpriceDropDown.Value       = 'Custom';
end

    standardConsumption = [0.12 0.15 0.13 0.21 0.14 0.17 0.1];
    standardGeneration  = [0.15 0.21 0.26 0 0 0 0];
    standardBuy         = [0 0 0 0.28 0.22 0.18 0.16];
    standardSell        = [0.12 0.15 0.10 0 0 0 0];

  % Make sure standard vectors are long enough
if n > length(standardConsumption)
    standardConsumption(end+1:n) = 0;
end

if n > length(standardGeneration)
    standardGeneration(end+1:n) = 0;
end

if n > length(standardBuy)
    standardBuy(end+1:n) = 0;
end

if n > length(standardSell)
    standardSell(end+1:n) = 0;
end

standardConsumption = standardConsumption(1:n);
standardGeneration  = standardGeneration(1:n);
standardBuy         = standardBuy(1:n);
standardSell        = standardSell(1:n);
    % Keep existing table data if possible
    data = app.UITable.Data;

 if isempty(data) || size(data,1) ~= 4

    data = zeros(4,n);

elseif size(data,2) ~= n

    oldData = data;
    data = zeros(4,n);

    colsToCopy = min(size(oldData,2), n);
    data(:,1:colsToCopy) = oldData(:,1:colsToCopy);

end

    % Row 1: Consumption
    switch app.ConsumptionDataDropDown.Value
        case 'Standard'
            data(1,:) = standardConsumption;
        case 'Zero'
            data(1,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    % Row 2: Generation
    switch app.GenerationDataDropDown.Value
        case 'Standard'
            data(2,:) = standardGeneration;
        case 'Zero'
            data(2,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    % Row 3: Buy price
    switch app.BuyPriceDropDown.Value
        case 'Standard'
            data(3,:) = standardBuy;
        case 'Zero'
            data(3,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    % Row 4: Sell price
    switch app.SellpriceDropDown.Value
        case 'Standard'
            data(4,:) = standardSell;
        case 'Zero'
            data(4,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    app.UITable.Data = data;

    % Labels
    colNames = cell(1,n);
    for i = 1:n
        colNames{i} = ['Actor ' num2str(i)];
    end

    app.UITable.ColumnName = colNames;
    app.UITable.RowName = {'Consumption','Generation','Buy price','Sell price'};

    % IMPORTANT:
    % suitable ColumnEditable works per column, not per row.
    % So if ANY row is Custom, the table columns become editable.
    isAnyCustom = strcmp(app.ConsumptionDataDropDown.Value,'Custom') || ...
                  strcmp(app.GenerationDataDropDown.Value,'Custom') || ...
                  strcmp(app.BuyPriceDropDown.Value,'Custom') || ...
                  strcmp(app.SellpriceDropDown.Value,'Custom');

    app.UITable.ColumnEditable = repmat(isAnyCustom,1,n);

end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     updateInputTable(app);
    app.InputPanel.Visible = 'on';
    app.OutputsPanel.Visible = 'off';
        end

        % Callback function
        function RunButtonPushed(app, event)

    inputs.P_grid = app.PgridEditField.Value;
    inputs.C_feedback = app.CfeedbackEditField.Value;
    inputs.C_networkfee = app.CnetworkfeeEditField.Value;
    inputs.C_platform = app.CplatformEditField.Value;

    inputs.timestep = app.TimestepEditField.Value;
    inputs.horizon = app.HorizonEditField.Value;

    inputs.switchCong = app.CongestionLimitSwitch.Value;
    inputs.limCong = app.LimitSpinner.Value;
    inputs.modeCong = app.CongestionmethodDropDown.Value;

    inputs.vecConsumption = [0.12 0.15 0.13 0.21 0.14 0.17 0.1];
    inputs.vecGeneration  = [0.15 0.21 0.26 0 0 0 0];
    inputs.vecBuy         = [0 0 0 0.28 0.22 0.18 0.16];
    inputs.vecSell        = [0.12 0.15 0.10 0 0 0 0];

    tableData = app.UITable.Data;
    inputs.vecConsumption = tableData(1,:);
    inputs.vecGeneration  = tableData(2,:);
    inputs.vecBuy         = tableData(3,:);
    inputs.vecSell        = tableData(4,:);

    inputs.pricingMechanism = app.PricingMechanismDropDown.Value;

    results = runP2PModel2(inputs);

    app.Spinner.Limits = [1 length(results.timeVec)];
    app.Spinner.RoundFractionalValues = 'on';

    app.UITable2.Data = results.tradingArray(:,:,1);
    app.UITable2.ColumnName = {'Seller','Buyer','Energy','Price'};

    plot(app.UIAxes, results.timeVec, results.vecP2P);
    title(app.UIAxes, 'P2P traded energy');

    plot(app.UIAxes2, results.timeVec, results.vecClearPrice);
    title(app.UIAxes2, 'Clearing price');

    plot(app.UIAxes3, results.timeVec, results.vecGridImport);
    hold(app.UIAxes3, 'on');
    plot(app.UIAxes3, results.timeVec, results.vecGridExport);
    hold(app.UIAxes3, 'off');
  
    legend(app.UIAxes3, 'Import', 'Export');
    title(app.UIAxes3, 'Grid exchange');

    % Choose timestep for network visualization
if strcmp(app.Switch.Value, 'Initial timestep')
    jNet = round(app.Spinner.Value);
else
    jNet = 1; % or use cumulative/max later
end

edgePercNow = results.edgePerc(:,jNet);
edgeList = results.edgeList;

% Create graph
Gnet = graph(edgeList(:,1), edgeList(:,2));

% Clear old plot
cla(app.NetworkAxes);

nNodes = numnodes(Gnet);

x = zeros(1,nNodes);
y = zeros(1,nNodes);

% Fixed positions for first 7 actors
x(1) = 1; y(1) = 3;
x(5) = 2; y(5) = 3;
x(6) = 2; y(6) = 2;
x(2) = 3; y(2) = 2;
x(4) = 4; y(4) = 2;
x(3) = 4; y(3) = 3;
x(7) = 3; y(7) = 1;

% Automatic positions for actors above 7
if nNodes > 7
    extraNodes = 8:nNodes;
    x(extraNodes) = linspace(1,4,length(extraNodes));
    y(extraNodes) = 0;
end

nodeLabels = cell(1,nNodes);
for k = 1:nNodes
    nodeLabels{k} = ['Actor ' num2str(k)];
end

p = plot(app.NetworkAxes, Gnet, ...
    'XData', x, ...
    'YData', y, ...
    'NodeLabel', nodeLabels, ...
    'LineWidth', 3);

% Color edges based on usage percentage
p.EdgeCData = edgePercNow;
p.LineWidth = 1 + 6*edgePercNow;

% Actor colour based on net energy position
% Net production = generation - consumption
jNode = jNet;

netProduction = results.arrayActor(2,:,jNode) - results.arrayActor(1,:,jNode);

% 1 = producer, 2 = consumer, 3 = neutral
nodeType = zeros(1,length(netProduction));

nodeType(netProduction > 1e-9) = 1;   % producer
nodeType(netProduction < -1e-9) = 2;  % consumer
nodeType(abs(netProduction) <= 1e-9) = 3; % neutral

% RGB colours
nodeColours = zeros(length(netProduction),3);

for n = 1:length(netProduction)
    if nodeType(n) == 1
        nodeColours(n,:) = [0.2 0.7 0.2];   % green = producer
    elseif nodeType(n) == 2
        nodeColours(n,:) = [0.9 0.2 0.2];   % red = consumer
    else
        nodeColours(n,:) = [0.6 0.6 0.6];   % grey = neutral
    end
end

p.NodeColor = nodeColours;
p.MarkerSize = 8;

colormap(app.NetworkAxes, jet);
colorbar(app.NetworkAxes);

caxis(app.NetworkAxes,[0 1]);

title(app.NetworkAxes,'Network edge utilization');
% Hide axes for network graph
app.NetworkAxes.XAxis.Visible = 'off';
app.NetworkAxes.YAxis.Visible = 'off';
app.NetworkAxes.Box = 'off';

xlabel(app.NetworkAxes,'');
ylabel(app.NetworkAxes,'');
zlabel(app.NetworkAxes,'');

app.NetworkAxes.XTick = [];
app.NetworkAxes.YTick = [];

value = app.ConstantdataovertimeCheckBox.Value;

if value == 1

    app.UIAxes.Visible = 'off';
    app.UIAxes2.Visible = 'off';
    app.UIAxes3.Visible = 'off';
    app.NetworkAxes.Visible = 'off';

    cla(app.UIAxes)
    cla(app.UIAxes2)
    cla(app.UIAxes3)
    cla(app.NetworkAxes)

    legend(app.UIAxes,'off');
    legend(app.UIAxes2,'off');
    legend(app.UIAxes3,'off');
    legend(app.NetworkAxes,'off');

else

    app.UIAxes.Visible = 'on';
    app.UIAxes2.Visible = 'on';
    app.UIAxes3.Visible = 'on';
    app.NetworkAxes.Visible = 'on';

end
           arrayActor = results.arrayActor;
           tradingArray = results.tradingArray;

switch app.Switch.Value

    case 'Initial timestep'

        j = app.Spinner.Value;

        p2p_kWh = results.vecP2P(j);
        gridImport_kWh = results.vecGridImport(j);
        gridExport_kWh = results.vecGridExport(j);

        paid_EUR = sum(arrayActor(6,:,j));
        received_EUR = sum(arrayActor(7,:,j));

        clearingPrice = results.vecClearPrice(j);

        dsoRevenue_EUR = sum(arrayActor(11,:,j));
        platformRevenue_EUR = sum(arrayActor(12,:,j));
        largerGridReference_EUR = sum(arrayActor(13,:,j));

          j = round(app.Spinner.Value);

        ledgerData = tradingArray(:,:,j);

        % Remove empty rows
        ledgerData = ledgerData(ledgerData(:,3) > 0,:);


    case 'Cumulative'

        p2p_kWh = sum(results.vecP2P);
        gridImport_kWh = sum(results.vecGridImport);
        gridExport_kWh = sum(results.vecGridExport);

        paid_EUR = sum(arrayActor(6,:,:), 'all');
        received_EUR = sum(arrayActor(7,:,:), 'all');

        clearingPrices = results.vecClearPrice(results.vecClearPrice > 0);

        if isempty(clearingPrices)
            clearingPrice = 0;
        else
            clearingPrice = mean(clearingPrices);
        end

        dsoRevenue_EUR = sum(arrayActor(11,:,:), 'all');
        platformRevenue_EUR = sum(arrayActor(12,:,:), 'all');
        largerGridReference_EUR = sum(arrayActor(13,:,:), 'all');

             ledgerData = [];

        for j = 1:size(tradingArray,3)

            stepData = tradingArray(:,:,j);

            % Remove empty rows
            stepData = stepData(stepData(:,3) > 0,:);

            if ~isempty(stepData)

                % Add timestep as first column
                stepData = [j * ones(size(stepData,1),1), stepData];

                ledgerData = [ledgerData; stepData];

            end
        end
       app.UITable2.ColumnName = {'Timestep','Seller','Buyer','Energy','Price'};
if strcmp(app.Switch.Value, 'Initial timestep')

    app.UITable2.Data = ledgerData;
    app.UITable2.ColumnName = {'Seller','Buyer','Energy','Price'};

else

    app.UITable2.Data = ledgerData;

end
end

app.UITable3.Data = [
    p2p_kWh, ...
    gridImport_kWh, ...
    gridExport_kWh, ...
    paid_EUR, ...
    received_EUR, ...
    clearingPrice, ...
    dsoRevenue_EUR, ...
    platformRevenue_EUR, ...
    largerGridReference_EUR
];

app.UITable3.ColumnName = { ...
    'P2P kWh', ...
    'Grid Import', ...
    'Grid Export', ...
    'Money Paid €', ...
    'Money Received €', ...
    'Clearing Price €/kWh', ...
    'DSO Revenue €', ...
    'Platform Revenue €', ...
    'Grid-only Reference €'};
        end

        % Callback function
        function ActorsSpinnerValueChanged(app, event)
 n = app.ActorsSpinner.Value;

    colNames = cell(1,n);
    for i = 1:n
        colNames{i} = ['Actor ' num2str(i)];
    end

    app.UITable.ColumnName = colNames;
    app.UITable.RowName = {'Consumption','Generation','Buy price','Sell price'};
    app.UITable.Data = zeros(4,n);
            updateInputTable(app);
        end

        % Callback function
        function UITableCellEdit(app, event)
 val = event.NewData;

    % Check if value is numeric
    if ~isnumeric(val) || isnan(val)
        % Revert to previous value
        data = app.UITable.Data;
        data(event.Indices(1), event.Indices(2)) = event.PreviousData;
        app.UITable.Data = data;

        % Show warning
        uialert(app.UIFigure, 'Only numeric values are allowed', 'Invalid input');
    end


        end

        % Value changed function: ConsumptionDataDropDown
        function ConsumptionDataDropDownValueChanged(app, event)
         updateInputTable(app);
            
        end

        % Value changed function: GenerationDataDropDown
        function GenerationDataDropDownValueChanged(app, event)
              updateInputTable(app);
            
        end

        % Value changed function: SellpriceDropDown
        function SellpriceDropDownValueChanged(app, event)
              updateInputTable(app);
            
        end

        % Value changed function: BuyPriceDropDown
        function BuyPriceDropDownValueChanged(app, event)
               updateInputTable(app);
            
        end

        % Callback function
        function ManualRefreshButtonPushed(app, event)
                updateInputTable(app);
        end

        % Button pushed function: GotoOuputsButton
        function GotoOuputsButtonPushed(app, event)

    RunButtonPushed(app, event);
    % switch view
    app.InputPanel.Visible = 'off';
    app.OutputsPanel.Visible = 'on';
    % Optional: make sure input panel is in front
    uistack(app.OutputsPanel,'top');
        end

        % Button pushed function: GotoInputsButton
        function GotoInputsButtonPushed(app, event)
            
    app.OutputsPanel.Visible = 'off';
    app.InputPanel.Visible = 'on';

        % Optional: make sure input panel is in front
    uistack(app.InputPanel,'top');
        end

        % Callback function
        function ConstantdataovertimeCheckBoxValueChanged(app, event)
            value = app.ConstantdataovertimeCheckBox.Value;
            if value == 1
                app.UIAxes.Visible = 'off';
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';

                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                legend(app.UIAxes,'off');
                legend(app.UIAxes2,'off');
                legend(app.UIAxes3,'off');
            elseif value == 0
                app.UIAxes.Visible = 'on';
                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'on';
            end
        end

        % Callback function
        function GotoOuputsButtonPushed2(app, event)
            
        end

        % Callback function
        function SpinnerValueChanged(app, event)
  if strcmp(app.Switch.Value, 'Initial timestep')
        RunButtonPushed(app, event);
    end
            
        end

        % Callback function
        function SwitchValueChanged(app, event)


    if strcmp(app.Switch.Value, 'Initial timestep')
        app.Spinner.Enable = 'on';
    else
        app.Spinner.Enable = 'off';
    end

    RunButtonPushed(app, event);


        end

        % Value changed function: ActorsSpinner
        function ActorsSpinnerValueChanged2(app, event)
       updateInputTable(app);
            
        end

        % Button pushed function: RunGradientButton
        function RunGradientButtonPushed(app, event)

disp('Started gradient simulation')
    %% Define congestion limit gradient
   %limValues = [0.1 0.2 0.3];
     limValues = linspace(0, 0.2 , 1000); 
    % Example: 50 simulations from 0.01 kWh to 1.00 kWh

    numRuns = length(limValues);

    %% Preallocate result arrays
    NumberActors = zeros(numRuns,1);
    CongestionLimit = zeros(numRuns,1);
    CongestionMethod = strings(numRuns,1);
    CongestionSwitch = strings(numRuns,1);

    P2P_kWh = zeros(numRuns,1);
    GridImport_kWh = zeros(numRuns,1);
    GridExport_kWh = zeros(numRuns,1);
    BuyerCosts_EUR = zeros(numRuns,1);
    SellerIncome_EUR = zeros(numRuns,1);
    ClearingPrice_EURperkWh = zeros(numRuns,1);
    DSORevenue_EUR = zeros(numRuns,1);
    PlatformRevenue_EUR = zeros(numRuns,1);
    GridOnlyReference_EUR = zeros(numRuns,1);

    %% Read fixed inputs once
    baseInputs.P_grid = app.PgridEditField.Value;
    baseInputs.C_feedback = app.CfeedbackEditField.Value;
    baseInputs.C_networkfee = app.CnetworkfeeEditField.Value;
    baseInputs.C_platform = app.CplatformEditField.Value;

    baseInputs.timestep = app.TimestepEditField.Value;
    baseInputs.horizon = app.HorizonEditField.Value;

    baseInputs.switchCong = 'On';
    baseInputs.modeCong = app.CongestionmethodDropDown.Value;

    tableData = app.UITable.Data;
    baseInputs.vecConsumption = tableData(1,:);
    baseInputs.vecGeneration  = tableData(2,:);
    baseInputs.vecBuy         = tableData(3,:);
    baseInputs.vecSell        = tableData(4,:);

    baseInputs.pricingMechanism = app.PricingMechanismDropDown.Value;

    %% Run simulations
    for r = 1:numRuns
        disp(['Run ' num2str(r) ' of ' num2str(numRuns)])

        inputs = baseInputs;
        inputs.limCong = limValues(r);

        results = runP2PModel2(inputs);

        arrayActor = results.arrayActor;

        NumberActors(r) = app.ActorsSpinner.Value;
        CongestionLimit(r) = limValues(r);
        CongestionMethod(r) = string(baseInputs.modeCong);
        CongestionSwitch(r) = "On";

        P2P_kWh(r) = sum(results.vecP2P);
        GridImport_kWh(r) = sum(results.vecGridImport);
        GridExport_kWh(r) = sum(results.vecGridExport);

        BuyerCosts_EUR(r) = sum(arrayActor(6,:,:),'all');
        SellerIncome_EUR(r) = sum(arrayActor(7,:,:),'all');

        clearingPrices = results.vecClearPrice(results.vecClearPrice > 0);
        if isempty(clearingPrices)
            ClearingPrice_EURperkWh(r) = 0;
        else
            ClearingPrice_EURperkWh(r) = mean(clearingPrices);
        end

        DSORevenue_EUR(r) = sum(arrayActor(11,:,:),'all');
        PlatformRevenue_EUR(r) = sum(arrayActor(12,:,:),'all');
        GridOnlyReference_EUR(r) = sum(arrayActor(13,:,:),'all');

        drawnow; % keeps app responsive
    end
disp('Finished all simulations')
    %% Export to Excel
    Timestamp = repmat(datetime("now"), numRuns, 1);
    exportTable = table( ...
        Timestamp, ...
        NumberActors, ...
        CongestionSwitch, ...
        CongestionMethod, ...
        CongestionLimit, ...
        P2P_kWh, ...
        GridImport_kWh, ...
        GridExport_kWh, ...
        BuyerCosts_EUR, ...
        SellerIncome_EUR, ...
        ClearingPrice_EURperkWh, ...
        DSORevenue_EUR, ...
        PlatformRevenue_EUR, ...
        GridOnlyReference_EUR);

    filename = fullfile(pwd,'P2P_congestion_gradient_results_pathspecific.xlsx');

    if isfile(filename)

    oldTable = readtable(filename);

    exportTable = [oldTable; exportTable];

end

writetable(exportTable, filename);
    winopen('P2P_congestion_gradient_results_pathspecific.xlsx')

    uialert(app.UIFigure, ...
        ['Gradient simulation complete. Results saved to: ' filename], ...
        'Simulation complete');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'OutputsPanel';
            app.UIAxes3 = uiaxes(app.OutputsPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.OutputsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.OutputsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NetworkAxes = uiaxes(app.OutputsPanel);
            title(app.NetworkAxes, 'Title')
            xlabel(app.NetworkAxes, 'X')
            ylabel(app.NetworkAxes, 'Y')
            zlabel(app.NetworkAxes, 'Z')
            app.UITable2 = uitable(app.OutputsPanel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.GotoInputsButton = uibutton(app.OutputsPanel, 'push');
            app.GotoInputsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoInputsButtonPushed, true);
            app.GotoInputsButton.Text = 'Go to Inputs';
            app.UITable3 = uitable(app.OutputsPanel);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.SwitchLabel = uilabel(app.OutputsPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.OutputsPanel, 'slider');
            app.Switch.Items = {'Initial timestep', 'Cumulative'};
            app.Switch.Value = 'Initial timestep';
            app.SpinnerLabel = uilabel(app.OutputsPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.OutputsPanel);
            app.Spinner.Limits = [1 48];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.Value = 1;
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.C_NetwrokFeeLabel = uilabel(app.InputPanel);
            app.C_NetwrokFeeLabel.Text = 'C_NetworkFee';
            app.CnetworkfeeEditField = uieditfield(app.InputPanel, 'numeric');
            app.CnetworkfeeEditField.Value = 0.04;
            app.C_FeedbackFeeLabel = uilabel(app.InputPanel);
            app.C_FeedbackFeeLabel.Text = 'C_FeedbackFee';
            app.CfeedbackEditField = uieditfield(app.InputPanel, 'numeric');
            app.CfeedbackEditField.Value = 0.14;
            app.P_GridLabel = uilabel(app.InputPanel);
            app.P_GridLabel.Text = 'P_Grid';
            app.PgridEditField = uieditfield(app.InputPanel, 'numeric');
            app.PgridEditField.Value = 0.24;
            app.C_plaformLabel = uilabel(app.InputPanel);
            app.C_plaformLabel.Text = 'C_plaform';
            app.CplatformEditField = uieditfield(app.InputPanel, 'numeric');
            app.CplatformEditField.Value = 0.01;
            app.TimestepLabel = uilabel(app.InputPanel);
            app.TimestepLabel.Text = 'Timestep';
            app.TimestepEditField = uieditfield(app.InputPanel, 'numeric');
            app.TimestepEditField.Value = 0.5;
            app.RunButton = uibutton(app.InputPanel, 'push');
            app.RunButton.Text = 'Run';
            app.HorizonLabel = uilabel(app.InputPanel);
            app.HorizonLabel.Text = 'Horizon';
            app.HorizonEditField = uieditfield(app.InputPanel, 'numeric');
            app.HorizonEditField.Value = 24;
            app.PricingMechanismDropDownLabel = uilabel(app.InputPanel);
            app.PricingMechanismDropDownLabel.Text = 'PricingMechanism';
            app.PricingMechanismDropDown = uidropdown(app.InputPanel);
            app.PricingMechanismDropDown.Items = {'MarketClearing', 'Option 2', 'Option 3', 'Option 4'};
            app.PricingMechanismDropDown.Value = 'MarketClearing';
            app.ActorsSpinnerLabel = uilabel(app.InputPanel);
            app.ActorsSpinnerLabel.Text = 'Actors';
            app.ActorsSpinner = uispinner(app.InputPanel);
            app.ActorsSpinner.Limits = [0 100];
            app.ActorsSpinner.ValueChangedFcn = createCallbackFcn(app, @ActorsSpinnerValueChanged2, true);
            app.ActorsSpinner.Value = 7;
            app.ConsumptionDataDropDownLabel = uilabel(app.InputPanel);
            app.ConsumptionDataDropDownLabel.Text = 'Consumption Data';
            app.ConsumptionDataDropDown = uidropdown(app.InputPanel);
            app.ConsumptionDataDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.ConsumptionDataDropDown.ValueChangedFcn = createCallbackFcn(app, @ConsumptionDataDropDownValueChanged, true);
            app.ConsumptionDataDropDown.Value = 'Standard';
            app.GenerationDataDropDownLabel = uilabel(app.InputPanel);
            app.GenerationDataDropDownLabel.Text = 'Generation Data';
            app.GenerationDataDropDown = uidropdown(app.InputPanel);
            app.GenerationDataDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.GenerationDataDropDown.ValueChangedFcn = createCallbackFcn(app, @GenerationDataDropDownValueChanged, true);
            app.GenerationDataDropDown.Value = 'Standard';
            app.SellpriceDropDownLabel = uilabel(app.InputPanel);
            app.SellpriceDropDownLabel.Text = 'Sell price';
            app.SellpriceDropDown = uidropdown(app.InputPanel);
            app.SellpriceDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.SellpriceDropDown.ValueChangedFcn = createCallbackFcn(app, @SellpriceDropDownValueChanged, true);
            app.SellpriceDropDown.Value = 'Standard';
            app.BuyPriceDropDownLabel = uilabel(app.InputPanel);
            app.BuyPriceDropDownLabel.Text = 'Buy Price';
            app.BuyPriceDropDown = uidropdown(app.InputPanel);
            app.BuyPriceDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.BuyPriceDropDown.ValueChangedFcn = createCallbackFcn(app, @BuyPriceDropDownValueChanged, true);
            app.BuyPriceDropDown.Value = 'Standard';
            app.UITable = uitable(app.InputPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ManualRefreshButton = uibutton(app.InputPanel, 'push');
            app.ManualRefreshButton.Text = 'Manual Refresh';
            app.GotoOuputsButton = uibutton(app.InputPanel, 'push');
            app.GotoOuputsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoOuputsButtonPushed, true);
            app.GotoOuputsButton.Text = 'Go to Ouputs';
            app.ConstantdataovertimeCheckBox = uicheckbox(app.InputPanel);
            app.ConstantdataovertimeCheckBox.Text = 'Constant  data over time';
            app.CongestionLimitSwitchLabel = uilabel(app.InputPanel);
            app.CongestionLimitSwitchLabel.Text = 'Congestion Limit';
            app.CongestionLimitSwitch = uiswitch(app.InputPanel, 'slider');
            app.LimitSpinnerLabel = uilabel(app.InputPanel);
            app.LimitSpinnerLabel.Text = 'Limit';
            app.LimitSpinner = uispinner(app.InputPanel);
            app.LimitSpinner.Value = Inf;
            app.CongestionmethodDropDownLabel = uilabel(app.InputPanel);
            app.CongestionmethodDropDownLabel.Text = 'Congestion method';
            app.CongestionmethodDropDown = uidropdown(app.InputPanel);
            app.CongestionmethodDropDown.Items = {'Fully Connected', 'Path specific (7 actors)'};
            app.CongestionmethodDropDown.Value = 'Fully Connected';
            app.RunGradientButton = uibutton(app.InputPanel, 'push');
            app.RunGradientButton.ButtonPushedFcn = createCallbackFcn(app, @RunGradientButtonPushed, true);
            app.RunGradientButton.Text = 'Run Gradient';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end